classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        Panel_3                  matlab.ui.container.Panel
        CalcularButton_2         matlab.ui.control.Button
        CALCULARTRABAJOREQUERIDOPARAELARREGLODECARGASLabel  matlab.ui.control.Label
        MedidaDropDown_6         matlab.ui.control.DropDown
        MedidaDropDown_6Label    matlab.ui.control.Label
        ValorQ3EditField_2       matlab.ui.control.NumericEditField
        ValorQ3EditField_2Label  matlab.ui.control.Label
        MedidaDropDown_5         matlab.ui.control.DropDown
        MedidaDropDown_5Label    matlab.ui.control.Label
        ValorQ2EditField_2       matlab.ui.control.NumericEditField
        ValorQ2EditField_2Label  matlab.ui.control.Label
        MedidaDropDown_4         matlab.ui.control.DropDown
        MedidaDropDown_4Label    matlab.ui.control.Label
        ValorQ1EditField_2       matlab.ui.control.NumericEditField
        ValorQ1EditField_2Label  matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        CalcularButton           matlab.ui.control.Button
        CALCULARMAGNITUDCAMPOELCTRICOLabel  matlab.ui.control.Label
        MedidaDropDown_3         matlab.ui.control.DropDown
        MedidaDropDown_3Label    matlab.ui.control.Label
        ValorQ3EditField         matlab.ui.control.NumericEditField
        ValorQ3EditFieldLabel    matlab.ui.control.Label
        MedidaDropDown_2         matlab.ui.control.DropDown
        MedidaDropDown_2Label    matlab.ui.control.Label
        ValorQ2EditField         matlab.ui.control.NumericEditField
        ValorQ2EditFieldLabel    matlab.ui.control.Label
        MedidaDropDown           matlab.ui.control.DropDown
        MedidaDropDownLabel      matlab.ui.control.Label
        ValorQ1EditField         matlab.ui.control.NumericEditField
        ValorQ1EditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function[q] = calcularMedida(app,q,medida)
            switch(medida)
                case("C")
                    q = q*1;
                case("μC")
                    q = q * 1*10^-6;
                case("nC")
                    q = q * 1*10^-9;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % El triangulo tiene lado a de 20 centimetros
            a = 20;
            q1 = app.ValorQ1EditField.Value;
            medidaQ1 = app.MedidaDropDown.Value;
            q1 = app.calcularMedida(q1,medidaQ1);
            q2 = app.ValorQ2EditField.Value;
            medidaQ2 = app.MedidaDropDown_2.Value;
            q2 = app.calcularMedida(q2,medidaQ2);
            q3 = app.ValorQ3EditField.Value;
            medidaQ3 = app.MedidaDropDown_3.Value;
            q3 = app.calcularMedida(q3,medidaQ3);
            graficarP(q1,q2,q3,a);
   
     
        end

        % Callback function: not associated with a component
        function CalcularButton_2Pushed(app, event)
            q1 = app.ValorQ1EditField_2.Value;
            medidaQ1 = app.MedidaDropDown_4.Value;
            q1 = app.calcularMedida(q1,medidaQ1);
            q2 = app.ValorQ2EditField_2.Value;
            medidaQ2 = app.MedidaDropDown_5.Value;
            q2 = app.calcularMedida(q2,medidaQ2);
            q3 = app.ValorQ3EditField_2.Value;
            medidaQ3 = app.MedidaDropDown_6.Value;
            q3 = app.calcularMedida(q3,medidaQ3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.ValorQ1EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ1EditFieldLabel.Text = 'Valor Q1';
            app.ValorQ1EditField = uieditfield(app.Panel_2, 'numeric');
            app.MedidaDropDownLabel = uilabel(app.Panel_2);
            app.MedidaDropDownLabel.Text = 'Medida';
            app.MedidaDropDown = uidropdown(app.Panel_2);
            app.MedidaDropDown.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown.Value = 'C';
            app.ValorQ2EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ2EditFieldLabel.Text = 'Valor Q2';
            app.ValorQ2EditField = uieditfield(app.Panel_2, 'numeric');
            app.ValorQ2EditField.Limits = [-Inf 0];
            app.MedidaDropDown_2Label = uilabel(app.Panel_2);
            app.MedidaDropDown_2Label.Text = 'Medida';
            app.MedidaDropDown_2 = uidropdown(app.Panel_2);
            app.MedidaDropDown_2.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_2.Value = 'C';
            app.ValorQ3EditFieldLabel = uilabel(app.Panel_2);
            app.ValorQ3EditFieldLabel.Text = 'Valor Q3';
            app.ValorQ3EditField = uieditfield(app.Panel_2, 'numeric');
            app.MedidaDropDown_3Label = uilabel(app.Panel_2);
            app.MedidaDropDown_3Label.Text = 'Medida';
            app.MedidaDropDown_3 = uidropdown(app.Panel_2);
            app.MedidaDropDown_3.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_3.Value = 'C';
            app.CALCULARMAGNITUDCAMPOELCTRICOLabel = uilabel(app.Panel_2);
            app.CALCULARMAGNITUDCAMPOELCTRICOLabel.Text = 'CALCULAR MAGNITUD CAMPO ELÉCTRICO';
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Panel_3 = uipanel(app.Panel);
            app.ValorQ1EditField_2Label = uilabel(app.Panel_3);
            app.ValorQ1EditField_2Label.Text = 'Valor Q1';
            app.ValorQ1EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.MedidaDropDown_4Label = uilabel(app.Panel_3);
            app.MedidaDropDown_4Label.Text = 'Medida';
            app.MedidaDropDown_4 = uidropdown(app.Panel_3);
            app.MedidaDropDown_4.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_4.Value = 'C';
            app.ValorQ2EditField_2Label = uilabel(app.Panel_3);
            app.ValorQ2EditField_2Label.Text = 'Valor Q2';
            app.ValorQ2EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.ValorQ2EditField_2.Limits = [-Inf 0];
            app.MedidaDropDown_5Label = uilabel(app.Panel_3);
            app.MedidaDropDown_5Label.Text = 'Medida';
            app.MedidaDropDown_5 = uidropdown(app.Panel_3);
            app.MedidaDropDown_5.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_5.Value = 'C';
            app.ValorQ3EditField_2Label = uilabel(app.Panel_3);
            app.ValorQ3EditField_2Label.Text = 'Valor Q3';
            app.ValorQ3EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.MedidaDropDown_6Label = uilabel(app.Panel_3);
            app.MedidaDropDown_6Label.Text = 'Medida';
            app.MedidaDropDown_6 = uidropdown(app.Panel_3);
            app.MedidaDropDown_6.Items = {'C', 'μC', 'nC'};
            app.MedidaDropDown_6.Value = 'C';
            app.CALCULARTRABAJOREQUERIDOPARAELARREGLODECARGASLabel = uilabel(app.Panel_3);
            app.CALCULARTRABAJOREQUERIDOPARAELARREGLODECARGASLabel.Text = 'CALCULAR TRABAJO REQUERIDO PARA EL ARREGLO DE CARGAS';
            app.CalcularButton_2 = uibutton(app.Panel_3, 'push');
            app.CalcularButton_2.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end